classdef PathPlanner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PathPlannerMenu_2               matlab.ui.container.Menu
        AboutPathPlannerMenu            matlab.ui.container.Menu
        SettingMenu_2                   matlab.ui.container.Menu
        KeyboardMappingMenu             matlab.ui.container.Menu
        WindowMenu                      matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        GridLayout10                    matlab.ui.container.GridLayout
        GridLayout13                    matlab.ui.container.GridLayout
        CommStatusPanel                 matlab.ui.container.Panel
        AutomaticModeSwitch             matlab.ui.control.Switch
        AutomaticModeSwitchLabel        matlab.ui.control.Label
        ExportLocationEditField         matlab.ui.control.EditField
        Label_6                         matlab.ui.control.Label
        ExportPathButton                matlab.ui.control.Button
        ClearLogButton                  matlab.ui.control.Button
        TCPCommLogTextArea              matlab.ui.control.TextArea
        CommunicationLogLabel           matlab.ui.control.Label
        StatusLamp                      matlab.ui.control.Lamp
        StatusLampLabel                 matlab.ui.control.Label
        DisconnectButton                matlab.ui.control.Button
        ConnectButton                   matlab.ui.control.Button
        GraphPanel                      matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        VisualizationTab                matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        VisualizePanel                  matlab.ui.control.UIAxes
        TrajectoryTab                   matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        UIAxes1                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        GridLayout12                    matlab.ui.container.GridLayout
        MRExUnitPanel                   matlab.ui.container.Panel
        GridLayout15                    matlab.ui.container.GridLayout
        PoseinWorldFramePanel           matlab.ui.container.Panel
        LabelPsi0                       matlab.ui.control.Label
        LabelCelsius_3                  matlab.ui.control.Label
        Theta0Label_2                   matlab.ui.control.Label
        LabelCelsius_2                  matlab.ui.control.Label
        LabelTheta0                     matlab.ui.control.Label
        Theta0Label                     matlab.ui.control.Label
        LabelPhi0                       matlab.ui.control.Label
        LabelCelsius                    matlab.ui.control.Label
        Phi0Label                       matlab.ui.control.Label
        XactLabel                       matlab.ui.control.Label
        LabelX0                         matlab.ui.control.Label
        LabelZ0                         matlab.ui.control.Label
        LabelY0                         matlab.ui.control.Label
        mmLabel_6                       matlab.ui.control.Label
        mmLabel_5                       matlab.ui.control.Label
        mmLabel_4                       matlab.ui.control.Label
        Z0Label                         matlab.ui.control.Label
        YactLabel                       matlab.ui.control.Label
        ConfigurationPanel              matlab.ui.container.Panel
        RightManipulatorDropDown        matlab.ui.control.DropDown
        RightManipulatorDropDownLabel   matlab.ui.control.Label
        LeftManipulatorDropDown         matlab.ui.control.DropDown
        LeftManipulatorDropDownLabel    matlab.ui.control.Label
        InitialTiltAngleEditField       matlab.ui.control.NumericEditField
        InitialTiltAngleEditFieldLabel  matlab.ui.control.Label
        TipDistanceEditField            matlab.ui.control.NumericEditField
        TipDistanceEditFieldLabel       matlab.ui.control.Label
        ModelDropDown                   matlab.ui.control.DropDown
        ModelDropDownLabel              matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        mmLabel_7                       matlab.ui.control.Label
        ManipulatorRight                matlab.ui.control.DropDown
        ManipulatorLeft                 matlab.ui.control.DropDown
        Image                           matlab.ui.control.Image
        MotionControlPanel              matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        GridLayout16                    matlab.ui.container.GridLayout
        SteppingPanel                   matlab.ui.container.Panel
        StepButton                      matlab.ui.control.Button
        SpeedSlider                     matlab.ui.control.Slider
        SpeedSliderLabel                matlab.ui.control.Label
        ToolAxialFeedPanel              matlab.ui.container.Panel
        dSlider_2                       matlab.ui.control.Slider
        dSlider_2Label                  matlab.ui.control.Label
        mmLabel_8                       matlab.ui.control.Label
        ZPlusButton_2                   matlab.ui.control.Button
        ZMinusButton_2                  matlab.ui.control.Button
        AxialFeedTargetEditField        matlab.ui.control.NumericEditField
        Theta0Label_4                   matlab.ui.control.Label
        GridLayout14                    matlab.ui.container.GridLayout
        PathPlanningPanel               matlab.ui.container.Panel
        StatusLabel                     matlab.ui.control.Label
        FidelitySlider                  matlab.ui.control.Slider
        FidelitySliderLabel             matlab.ui.control.Label
        IntervalSlider                  matlab.ui.control.Slider
        IntervalSliderLabel             matlab.ui.control.Label
        GetPoseButton                   matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        SendButton                      matlab.ui.control.Button
        PlanButton                      matlab.ui.control.Button
        PoseControlRelativetoWorldFramePanel  matlab.ui.container.Panel
        dAngleSlider                    matlab.ui.control.Slider
        Label_9                         matlab.ui.control.Label
        dStepSlider                     matlab.ui.control.Slider
        DIstanceIncrementLabel          matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        PsiPlusButton                   matlab.ui.control.Button
        PsiMinusButton                  matlab.ui.control.Button
        PsiTargetEditField              matlab.ui.control.NumericEditField
        Theta0Label_3                   matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        ThetaPlusButton                 matlab.ui.control.Button
        ThetaMinusButton                matlab.ui.control.Button
        ThetaTargetEditField            matlab.ui.control.NumericEditField
        ThetaLabel                      matlab.ui.control.Label
        PhiPlusButton                   matlab.ui.control.Button
        PhiMinusButton                  matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        PhiLabel                        matlab.ui.control.Label
        mmLabel_3                       matlab.ui.control.Label
        ZPlusButton                     matlab.ui.control.Button
        ZMinusButton                    matlab.ui.control.Button
        Z_setLabel                      matlab.ui.control.Label
        YPlusButton                     matlab.ui.control.Button
        YMinusButton                    matlab.ui.control.Button
        Y_setLabel                      matlab.ui.control.Label
        mmLabel_2                       matlab.ui.control.Label
        X_setLabel                      matlab.ui.control.Label
        mmLabel                         matlab.ui.control.Label
        XPlusButton                     matlab.ui.control.Button
        XMinusButton                    matlab.ui.control.Button
        PhiTargetEditField              matlab.ui.control.NumericEditField
        ZTargetEditField                matlab.ui.control.NumericEditField
        YTargetEditField                matlab.ui.control.NumericEditField
        XTargetEditField                matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        XTarget = 0                    % Target X position [mm]
        YTarget = 0                    % Target Y position [mm]
        ZTarget = 0                    % Target Z position [mm]
        PhiTarget = 0                  % Target Phi angle [degrees]
        ThetaTarget = -45              % Target Theta angle [degrees]
        PsiTarget = 0                  % Target Psi angle [degrees]

        pathPlannerClient               % TCP Client instance
        
        trajectoryTime                  % Time vector for trajectory
        trajectoryData                  % Joint trajectory data (Nx6 matrix)

        currentState = 'idle'           % Track current operational state
        
        param                           % Parameter Manager
    end
    
    methods (Access = private)
        
        function updateUIState(app, newState)
            % Update UI and buttons
            app.currentState = newState;
            
            switch newState
                case 'Idle'
                    app.ConnectButton.Enable = 'on';
                    app.DisconnectButton.Enable = 'off';
                    app.GetPoseButton.Enable = 'off';
                    app.PlanButton.Enable = 'off';
                    app.SendButton.Enable = 'off';
                    app.StartButton.Enable = 'off';
                    app.ExportPathButton.Enable = 'off';
                    app.StatusLamp.Color = [0.5 0.5 0.5]; % Gray

                case 'Disconnected'
                    app.ConnectButton.Enable = 'on';
                    app.DisconnectButton.Enable = 'off';
                    app.PlanButton.Enable = 'off';
                    app.SendButton.Enable = 'off';
                    app.StartButton.Enable = 'off';
                    app.StatusLamp.Color = [1 0 0]; % Red
                    
                case 'Connected'
                    app.ConnectButton.Enable = 'off';
                    app.DisconnectButton.Enable = 'on';
                    app.GetPoseButton.Enable = 'on';
                    app.PlanButton.Enable = 'on';
                    app.SendButton.Enable = 'off';
                    app.StartButton.Enable = 'off';
                    app.StatusLamp.Color = [0 1 0]; % Green
                    
                case 'TrajectoryPlanned'
                    app.ConnectButton.Enable = 'off';
                    app.DisconnectButton.Enable = 'on';
                    app.GetPoseButton.Enable = 'on';
                    app.PlanButton.Enable = 'on';       % Allow for re-planning
                    app.SendButton.Enable = 'on';
                    app.StartButton.Enable = 'off';   % Must send before execute
                    app.ExportPathButton.Enable = 'on';
                    
                case 'TrajectorySent'
                    app.ConnectButton.Enable = 'off';
                    app.DisconnectButton.Enable = 'on';
                    app.GetPoseButton.Enable = 'on';
                    app.PlanButton.Enable = 'on';
                    app.SendButton.Enable = 'off';      % once sent, cannot resend
                    app.StartButton.Enable = 'on';
                    
                case 'Executing'
                    app.ConnectButton.Enable = 'off';
                    app.DisconnectButton.Enable = 'off';% Disconnect not allowed during executing path
                    app.GetPoseButton.Enable = 'on';
                    app.PlanButton.Enable = 'off';
                    app.SendButton.Enable = 'off';
                    app.StartButton.Enable = 'off';
                    
                case 'ExecutionComplete'
                    app.ConnectButton.Enable = 'off';
                    app.DisconnectButton.Enable = 'on';
                    app.GetPoseButton.Enable = 'on';
                    app.PlanButton.Enable = 'on';
                    app.SendButton.Enable = 'off';      % Disable since trajectory was consumed
                    app.StartButton.Enable = 'off';   % % Disable since trajectory was consumed
            end
            
            app.logMessage(sprintf('UI State changed to: %s', newState));
        end

        % Event handlers
        function onStatusUpdate(app, ~, eventData)
            app.logMessage(eventData.Message);
        end
        
        % Centralized trigger for Automatic Mode
        function triggerAutoPathPlanning(app)
            % Check if Automatic Mode is enabled via the Switch
            isAutoMode = strcmpi(app.AutomaticModeSwitch.Value, 'On');
            
            if isAutoMode
                % Check connection status before proceeding
                if isempty(app.pathPlannerClient) || ~app.pathPlannerClient.isConnected
                    app.logMessage('Auto Mode: Cannot plan path. Not connected.');
                    return;
                end
                
                app.logMessage('Auto Mode: Triggering automatic sequence...');
                
                % Start the chain by clicking the Plan button logically
                % We reuse the logic inside PlanButtonPushed to ensure consistency
                app.PlanButtonPushed(); 
            end
        end

        % Event handler when trajectory is ready for execution
        function onTrajectoryReady(app, ~, eventData)
            app.updateUIState('TrajectoryPlanned');
            app.StatusLabel.Text = 'Trajectory planned successfully';

            % Update trajectory plots if data is available
            if isprop(eventData, 'Data') && ~isempty(eventData.Data)
                app.updateTrajectoryPlots(eventData.Data);
            end

            % Visualize Current pose AND Target pose (Ghost frame)
            app.updatePoseVisualization('ShowTarget', true);
            
            % --- AUTOMATIC MODE LOGIC: LINK 1 ---
            % If Auto Mode is On, immediately Send the path
            if strcmpi(app.AutomaticModeSwitch.Value, 'On')
                app.logMessage('Auto Mode: Trajectory ready. Sending path...');
                % Pause briefly to allow UI to render (optional but recommended)
                pause(0.1); 
                app.SendButtonPushed();
            end
        end
        
        % Event handler when trajectory data is received by the controller
        function onPathDataReceived(app, ~, eventData)
            app.updateUIState('TrajectorySent');
            app.StatusLabel.Text = 'Trajectory sent, ready for execution';
            app.logMessage(eventData.Message);
            
            % --- AUTOMATIC MODE LOGIC: LINK 2 ---
            % If Auto Mode is On, immediately Start execution
            if strcmpi(app.AutomaticModeSwitch.Value, 'On')
                app.logMessage('Auto Mode: Data received. Starting execution...');
                pause(0.1);
                app.StartButtonPushed();
            end
        end

        function onPathExecutionStarted(app, ~, eventData)
            % Handle path execution started event
            app.updateUIState('Executing');
            app.StatusLabel.Text = 'Executing trajectory...';
       
            app.logMessage(eventData.Message);
        end

        % Handle successful trajectory execution
        function onTrajectoryExecuted(app, ~, eventData)
            app.updateUIState('ExecutionComplete');
            app.StatusLabel.Text = 'Trajectory execution completed successfully!';
            
            app.logMessage(eventData.Message);
            app.logMessage('Ready for next operation');

            pause(0.1); % Brief pause for stability
            app.syncTargetsToCurrentPose();
        end

        % Handle path execution failure
        function onPathExecutionFailed(app, ~, eventData)
            app.updateUIState('TrajectorySent');
            app.StatusLabel.Text = 'Path execution failed';
            app.logMessage(eventData.Message);
            
            % Show alert to user
            uialert(app.UIFigure, eventData.Message, 'Path Execution Failed');
        end

        % Synchronize Target fields with Current Real Pose
        function syncTargetsToCurrentPose(app)
            % Check connection
            if isempty(app.pathPlannerClient) || ~app.pathPlannerClient.isConnected
                % Only warn if trying to switch to Auto mode
                if strcmpi(app.AutomaticModeSwitch.Value, 'On')
                     uialert(app.UIFigure, 'Cannot sync pose. Not connected.', 'Connection Error');
                     app.AutomaticModeSwitch.Value = 'Off'; % Abort auto mode
                end
                return;
            end
        
            try
                % Avoid UI blocking, show status
                app.StatusLabel.Text = 'Syncing pose data...';
                drawnow;

                % 2. Get Pose from Hardware (This executes getStatus internally)
                [~, ~] = app.pathPlannerClient.GetPose();
        
                % 3. Update "Actual" Labels (UI Display)
                % Convert meters/rad to mm/deg for display
                app.LabelX0.Text = sprintf('%.2f', app.param.X0 * 1e3);
                app.LabelY0.Text = sprintf('%.2f', app.param.Y0 * 1e3);
                app.LabelZ0.Text = sprintf('%.2f', app.param.Z0 * 1e3);
                app.LabelPhi0.Text = sprintf('%.2f', app.param.Phi0);
                app.LabelTheta0.Text = sprintf('%.2f', app.param.Theta0);
                app.LabelPsi0.Text = sprintf('%.2f', app.param.Psi0);
        
                % 4. Update "Target" Fields & Properties (Sync Input to Reality)
                % This prevents jumps when starting auto mode
                
                app.XTarget = app.param.X0 * 1e3;
                app.XTargetEditField.Value = app.XTarget;
                
                app.YTarget = app.param.Y0 * 1e3;
                app.YTargetEditField.Value = app.YTarget;
                
                app.ZTarget = app.param.Z0 * 1e3;
                app.ZTargetEditField.Value = app.ZTarget;
                
                app.PhiTarget = app.param.Phi0;
                app.PhiTargetEditField.Value = app.PhiTarget;
                
                app.ThetaTarget = app.param.Theta0;
                app.ThetaTargetEditField.Value = app.ThetaTarget;
                
                app.PsiTarget = app.param.Psi0;
                app.PsiTargetEditField.Value = app.PsiTarget;
        
                % Update Layout
                app.updatePoseVisualization();
                app.StatusLabel.Text = 'Pose synced (Auto Mode Ready)';
                app.logMessage('Pose synced: Targets updated to match actual robot position.');
        
            catch ME
                app.StatusLabel.Text = 'Error syncing pose';
                app.logMessage(['Error syncing pose: ' ME.message]);
                % Safety: Turn off auto mode if sync fails
                if strcmpi(app.AutomaticModeSwitch.Value, 'On')
                     app.AutomaticModeSwitch.Value = 'Off'; 
                end
            end
        end

        % Event handler for connection state changes
        function onConnectionStateChanged(app, ~, ~)
            if app.pathPlannerClient.isConnected
                app.updateUIState('Connected');
            else
                app.updateUIState('Disconnected');
            end
        end

        % Helper function to log messages with timestamp
        function logMessage(app, message)
            timestamp = datestr(now, 'HH:MM:SS.FFF');
            logEntry = sprintf('[%s] %s', timestamp, message);
            
            % Handle cell array format for TextArea
            currentText = app.TCPCommLogTextArea.Value;
            if iscell(currentText)
                if isempty(currentText)
                    app.TCPCommLogTextArea.Value = {logEntry};
                else
                    app.TCPCommLogTextArea.Value = [currentText; {logEntry}];
                end
            else
                if isempty(currentText)
                    app.TCPCommLogTextArea.Value = {logEntry};
                else
                    % Convert string to cell array
                    app.TCPCommLogTextArea.Value = {currentText; logEntry};
                end
            end
            
            % Limit log size to prevent memory issue
            if iscell(app.TCPCommLogTextArea.Value)
                if length(app.TCPCommLogTextArea.Value) > 1000
                    app.TCPCommLogTextArea.Value = app.TCPCommLogTextArea.Value(end-800:end);
                end
            end
        end

        % Wrapper method to update trajectory plots with new data
        function updateTrajectoryPlots(app, trajectoryData)
            if isempty(trajectoryData) || ~isstruct(trajectoryData)
                return;
            end
            onPlotTrajectory(app, trajectoryData);
        end

        % Wrapper method to call the external visualization function
        function updatePoseVisualization(app, varargin)
            % This calls the external file src/Callbacks/onVisualizePose.m
            % Passing 'app' as the first argument allows the function to access UIAxes
            if exist('onVisualizePose', 'file') == 2
                onVisualizePose(app, app.param, varargin{:});
            else
                warning('onVisualizePose.m not found on path');
            end
        end
        
        % Close request function
        function UIFigureCloseRequest(app, ~)
            % Ensure proper cleanup when closing the app
            if ~isempty(app.pathPlannerClient) && app.pathPlannerClient.isConnected
                app.pathPlannerClient.disconnect();
            end

            % Close Simulink models
            bdclose('all');
            
            % Delete the app
            delete(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.XTargetEditField.Value = app.XTarget;
            app.YTargetEditField.Value = app.YTarget;
            app.ZTargetEditField.Value = app.ZTarget;
            app.PhiTargetEditField.Value = app.PhiTarget;
            app.ThetaTargetEditField.Value = app.ThetaTarget;

            % Initialize TCP client
            app.pathPlannerClient = PathPlannerClient();

            app.param = app.pathPlannerClient.getParamObj();

            % Add listenser for events
            addlistener(app.pathPlannerClient, 'StatusUpdate', @app.onStatusUpdate);
            addlistener(app.pathPlannerClient, 'TrajectoryReady', @app.onTrajectoryReady);
            addlistener(app.pathPlannerClient, 'PathDataReceived', @app.onPathDataReceived);
            addlistener(app.pathPlannerClient, 'TrajectoryExecuted', @app.onTrajectoryExecuted);
            addlistener(app.pathPlannerClient, 'ConnectionStateChanged', @app.onConnectionStateChanged);
            addlistener(app.pathPlannerClient, 'PathExecutionStarted', @app.onPathExecutionStarted);
            addlistener(app.pathPlannerClient, 'PathExecutionFailed', @app.onPathExecutionFailed);
            
            % Set initial states
            app.updateUIState('Idle');

            % Initialize Visualization Panel (World & Tool Frame)
            app.updatePoseVisualization();

            % Log startup
            app.logMessage('PathPlanner App started. Ready to connect to Controller Server.');
        end

        % Button pushed function: PlanButton
        function PlanButtonPushed(app, event)
            % Check connection status
            if isempty(app.pathPlannerClient) || ~app.pathPlannerClient.isConnected
                uialert(app.UIFigure, 'Please connect to Controller Server first', 'Not Connected');
                return;
            end
            
            try
                app.PlanButton.Enable = 'off';
                app.StatusLabel.Text = 'Planning trajectory...';
                drawnow;

                % Set IK/FK model from the dropdown menu
                baseModel = app.ModelDropDown.Value;
                newModelFKName = string(baseModel) + "_FK";
                newModelIKName = string(baseModel) + "_IK";

                app.param.updateParameter('simulation', 'modelFK', newModelFKName);
                app.param.updateParameter('simulation', 'modelIK', newModelIKName);

                % Update target pose in client config (for onCalcIK to read)
                app.param.XTarget = app.XTargetEditField.Value * 1e-3;
                app.param.YTarget = app.YTargetEditField.Value * 1e-3;
                app.param.ZTarget = app.ZTargetEditField.Value * 1e-3;
                app.param.PhiTarget = app.PhiTargetEditField.Value;
                app.param.ThetaTarget = app.ThetaTargetEditField.Value;
                app.param.PsiTarget = app.PsiTargetEditField.Value;
                
                % Plan path (calculates trajectory but doesn't send to controller yet)
                success = app.pathPlannerClient.PlanPath();
                
                if success
                    app.StatusLabel.Text = 'Trajectory planned successfully';
                else
                    app.StatusLabel.Text = 'Failed to plan trajectory';
                end
                
            catch ME
                app.StatusLabel.Text = 'Error planning trajectory';
                uialert(app.UIFigure, ME.message, 'Plan Path Error');
            end
            
            app.PlanButton.Enable = 'on';
        end

        % Button pushed function: XPlusButton
        function XPlusButtonPushed(app, event)
            currentValue = app.XTargetEditField.Value;
            newValue = currentValue + app.dStepSlider.Value;

            if newValue <= app.XTargetEditField.Limits(2)
                app.XTarget = newValue;
                app.XTargetEditField.Value = newValue;
            end

            % Trigger Automatic Sequence if enabled
            app.triggerAutoPathPlanning();
        end

        % Button pushed function: XMinusButton
        function XMinusButtonPushed(app, event)
            currentValue = app.XTargetEditField.Value;
            newValue = currentValue - app.dStepSlider.Value;

            if newValue >= app.XTargetEditField.Limits(1)
                app.XTarget = newValue;
                app.XTargetEditField.Value = newValue;
            end

            % Trigger Automatic Sequence if enabled
            app.triggerAutoPathPlanning();
        end

        % Button pushed function: YPlusButton
        function YPlusButtonPushed(app, event)
            currentValue = app.YTargetEditField.Value;
            newValue = currentValue + app.dStepSlider.Value;

            if newValue <= app.YTargetEditField.Limits(2)
                app.YTarget = newValue;
                app.YTargetEditField.Value = newValue;
            end

            % Trigger Automatic Sequence if enabled
            app.triggerAutoPathPlanning();
        end

        % Button pushed function: YMinusButton
        function YMinusButtonPushed(app, event)
            currentValue = app.YTargetEditField.Value;
            newValue = currentValue - app.dStepSlider.Value;

            if newValue >= app.YTargetEditField.Limits(1)
                app.YTarget = newValue;
                app.YTargetEditField.Value = newValue;
            end

            % Trigger Automatic Sequence if enabled
            app.triggerAutoPathPlanning();
        end

        % Button pushed function: ZPlusButton
        function ZPlusButtonPushed(app, event)
            currentValue = app.ZTargetEditField.Value;
            newValue = currentValue + app.dStepSlider.Value;

            if newValue <= app.ZTargetEditField.Limits(2)
                app.ZTarget = newValue;
                app.ZTargetEditField.Value = newValue;
            end

            % Trigger Automatic Sequence if enabled
            app.triggerAutoPathPlanning();
        end

        % Button pushed function: ZMinusButton
        function ZMinusButtonPushed(app, event)
            currentValue = app.ZTargetEditField.Value;
            newValue = currentValue - app.dStepSlider.Value;

            if newValue >= app.ZTargetEditField.Limits(1)
                app.ZTarget = newValue;
                app.ZTargetEditField.Value = newValue;
            end

            % Trigger Automatic Sequence if enabled
            app.triggerAutoPathPlanning();
        end

        % Button pushed function: PhiPlusButton
        function PhiPlusButtonPushed(app, event)
            currentValue = app.PhiTargetEditField.Value;
            newValue = currentValue + app.dAngleSlider.Value;

            if newValue <= app.PhiTargetEditField.Limits(2)
                app.PhiTarget = newValue;
                app.PhiTargetEditField.Value = newValue;
            end

            % Trigger Automatic Sequence if enabled
            app.triggerAutoPathPlanning();
        end

        % Button pushed function: PhiMinusButton
        function PhiMinusButtonPushed(app, event)
            currentValue = app.PhiTargetEditField.Value;
            newValue = currentValue - app.dAngleSlider.Value;

            if newValue >= app.PhiTargetEditField.Limits(1)
                app.PhiTarget = newValue;
                app.PhiTargetEditField.Value = newValue;
            end

            % Trigger Automatic Sequence if enabled
            app.triggerAutoPathPlanning();
        end

        % Button pushed function: GetPoseButton
        function GetPoseButtonPushed(app, event)
            % Check if connected to controller
            if ~isempty(app.pathPlannerClient) && app.pathPlannerClient.isConnected
                try
                    app.GetPoseButton.Enable = 'off';
                    app.StatusLabel.Text = 'Getting current pose...';
                    drawnow;
                    
                    % Set FK model from the dropdown menu
                    baseModel = app.ModelDropDown.Value;
                    newModelFKName = string(baseModel) + "_FK";
    
                    app.param.setParam('modelFK', newModelFKName);
                    
                    % Obtain and designate manipulator IDs
                    manipulatorID1 = app.ManipulatorLeft.Value;
                    manipulatorID2 = app.ManipulatorRight.Value;
                    
                    app.param.setParam('manipulatorID1', manipulatorID1);
                    app.param.setParam('manipulatorID2', manipulatorID2);

                    % Call FK calculation through client config
                    [~, elapsedTime] = app.pathPlannerClient.GetPose();
                    
                    % Update UI with calculated pose (already in m from onCalcFK)
                    app.LabelX0.Text = sprintf('%.2f', app.param.X0 * 1e3);
                    app.LabelY0.Text = sprintf('%.2f', app.param.Y0 * 1e3);
                    app.LabelZ0.Text = sprintf('%.2f', app.param.Z0 * 1e3);
                    app.LabelPhi0.Text = sprintf('%.2f', app.param.Phi0);
                    app.LabelTheta0.Text = sprintf('%.2f', app.param.Theta0);
                    app.LabelPsi0.Text = sprintf('%.2f', app.param.Psi0);

                    app.StatusLabel.Text = sprintf('Pose updated in %.2f s', elapsedTime);
                    
                    % Update Visualization to show new Tool Frame position
                    app.updatePoseVisualization();

                catch ME
                    app.StatusLabel.Text = 'Error getting pose';
                    uialert(app.UIFigure, ME.message, 'Get Pose Error');
                end
                
                app.GetPoseButton.Enable = 'on';
            else
                % Not connected - show alert
                uialert(app.UIFigure, 'Please connect to Controller Server first', 'Not Connected');
            end
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.ConnectButton.Enable = 'off';
            app.logMessage('Connecting to Controller Server...');
            
            % Attempt connection
            success = app.pathPlannerClient.connect();
            
            if success
                app.DisconnectButton.Enable = 'on';
                app.StatusLamp.Color = [0 1 0]; % Green
                app.logMessage('Successfully connected to Controller Server');
            else
                app.ConnectButton.Enable = 'on';
                app.StatusLamp.Color = [1 0 0]; % Red
                app.logMessage('Failed to connect to Controller Server');
            end
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            app.pathPlannerClient.disconnect();

            app.logMessage('Disconnected from Controller Server');
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            % Send planned trajectory to controller
            if isempty(app.pathPlannerClient) || ~app.pathPlannerClient.isConnected
                uialert(app.UIFigure, 'Please connect to Controller Server first', 'Not Connected');
                return;
            end
            
            try
                app.SendButton.Enable = 'off';
                app.StatusLabel.Text = 'Sending trajectory to controller...';
                
                % Send the planned path to controller
                success = app.pathPlannerClient.SendPath();
                
                if success
                    app.StatusLabel.Text = 'Trajectory sent successfully';
                else
                    app.StatusLabel.Text = 'Failed to send trajectory';
                    app.SendButton.Enable = 'on';
                    app.StartButton.Enable = 'off';
                end
                
            catch ME
                app.StatusLabel.Text = 'Error sending trajectory';
                uialert(app.UIFigure, ME.message, 'Send Path Error');
                app.SendButton.Enable = 'on';
                app.StartButton.Enable = 'off';
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % Execute the trajectory on the controller
            if isempty(app.pathPlannerClient) || ~app.pathPlannerClient.isConnected
                uialert(app.UIFigure, 'Please connect to Controller Server first', 'Not Connected');
                return;
            end
            
            try
                app.StartButton.Enable = 'off';
                app.StatusLabel.Text = 'Sending execution command...';

                % Obtain the interval value for executing path tracking
                intervalVal = app.IntervalSlider.Value;
                
                % Set path tracking paramters
                app.param.setParam('interval', intervalVal);
                
                % Execute the path on controller
                success = app.pathPlannerClient.StartPath();
                
                if ~success
                    % Command failed to send
                    app.StatusLabel.Text = 'Failed to send execution command';
                    app.StartButton.Enable = 'on';
                end
                % If success, the events will handle UI updates
                
            catch ME
                app.StatusLabel.Text = 'Error executing trajectory';
                uialert(app.UIFigure, ME.message, 'Execute Path Error');
                app.StartButton.Enable = 'on';
            end
        end

        % Button pushed function: ClearLogButton
        function ClearLogButtonPushed(app, event)
            % Clear the communication log text area
            app.TCPCommLogTextArea.Value = {''};
        end

        % Button pushed function: ThetaPlusButton
        function ThetaPlusButtonPushed(app, event)
            currentValue = app.ThetaTargetEditField.Value;
            % Step value is set by the slider below
            newValue = currentValue + app.dAngleSlider.Value;

            if newValue <= app.ThetaTargetEditField.Limits(2)
                app.ThetaTarget = newValue;
                app.ThetaTargetEditField.Value = newValue;
            end

            % Trigger Automatic Sequence if enabled
            app.triggerAutoPathPlanning();
        end

        % Button pushed function: ThetaMinusButton
        function ThetaMinusButtonPushed(app, event)
            currentValue = app.ThetaTargetEditField.Value;
            newValue = currentValue - app.dAngleSlider.Value;

            if newValue >= app.ThetaTargetEditField.Limits(1)
                app.ThetaTarget = newValue;
                app.ThetaTargetEditField.Value = newValue;
            end

            % Trigger Automatic Sequence if enabled
            app.triggerAutoPathPlanning();
        end

        % Button pushed function: ExportPathButton
        function ExportPathButtonPushed(app, event)
            % 1. Validation: Ensure trajectory data is available
            if isempty(app.pathPlannerClient) || ~app.pathPlannerClient.isTrajectoryReady()
                uialert(app.UIFigure, ...
                    'No trajectory data available. Please generate a path first.', ...
                    'Export Error');
                return;
            end
        
            % 2. Data Retrieval: Get trajectory from Client
            % Retrieve the last calculated trajectory structure
            trajStruct = app.pathPlannerClient.getLastTrajectoryData();
            
            % Extract Time and Position data (Original Unit: Meters)
            timeData = trajStruct.qTime;
            posData_Meters = trajStruct.qData; 
            
            % 3. Unit Conversion: Meters to Micrometers (um)
            % Convert position to microns to match Controller protocol standards
            posData_Microns = posData_Meters * 1e6;
            
            % Combine into a single matrix: [Time, X1, Y1, Z1, X2, Y2, Z2]
            exportMatrix = [timeData, posData_Microns];
            
            % Define CSV Headers for clarity
            varNames = {'Time', 'X1_um', 'Y1_um', 'Z1_um', 'X2_um', 'Y2_um', 'Z2_um'};
            
            % Create Table object for convenient CSV export
            exportTable = array2table(exportMatrix, 'VariableNames', varNames);
            
            % 4. File Path Configuration
            % Retrieve the target directory from the UI Edit Field
            targetDir = app.ExportLocationEditField.Value;
            
            % Fallback: Use default 'data' folder in project root if field is empty
            if isempty(targetDir)
                targetDir = fullfile(pwd, 'data');
            end
            
            % Directory Validation: Check if folder exists, create if missing
            if ~isfolder(targetDir)
                try
                    % Attempt to create the directory structure
                    mkdir(targetDir);
                catch ME
                    uialert(app.UIFigure, ...
                        ['Failed to create directory: ', targetDir], ...
                        'Directory Error');
                    return;
                end
            end
        
            % Generate a timestamped filename to prevent overwriting
            timestamp = datestr(now, 'yyyymmdd_HHMMSS');
            defaultFileName = sprintf('Trajectory_%s.csv', timestamp);
            
            % Construct the full initial path for the dialog
            fullDefaultPath = fullfile(targetDir, defaultFileName);
            
            % Open File Selection Dialog (uiputfile)
            % This starts in the user-specified directory but allows final modification
            [file, path] = uiputfile({'*.csv', 'CSV Files (*.csv)'}, ...
                                     'Save Trajectory Data', ...
                                     fullDefaultPath);
            
            % Check if the user cancelled the dialog
            if isequal(file, 0)
                return;
            end
            
            % Construct the final absolute file path
            finalFilePath = fullfile(path, file);
            
            % 5. Data Export Execution
            try
                writetable(exportTable, finalFilePath);
                
                % Provide success feedback to the user
                uialert(app.UIFigure, ...
                    sprintf('Data successfully exported to:\n%s', finalFilePath), ...
                    'Export Successful');
                    
            catch ME
                % Handle write errors (e.g., file open in another program)
                uialert(app.UIFigure, ...
                    ['Export failed: ', ME.message], ...
                    'Write Error');
            end
        end

        % Button pushed function: PsiMinusButton
        function PsiMinusButtonPushed(app, event)
            currentValue = app.PsiTargetEditField.Value;
            newValue = currentValue - app.dAngleSlider.Value;

            if newValue >= app.PsiTargetEditField.Limits(1)
                app.PsiTarget = newValue;
                app.PsiTargetEditField.Value = newValue;
            end

            % Trigger Automatic Sequence if enabled
            app.triggerAutoPathPlanning();
        end

        % Button pushed function: PsiPlusButton
        function PsiPlusButtonPushed(app, event)
            currentValue = app.PsiTargetEditField.Value;
            newValue = currentValue + app.dAngleSlider.Value;

            if newValue <= app.PsiTargetEditField.Limits(2)
                app.PsiTarget = newValue;
                app.PsiTargetEditField.Value = newValue;
            end

            % Trigger Automatic Sequence if enabled
            app.triggerAutoPathPlanning();
        end

        % Value changed function: AutomaticModeSwitch
        function AutomaticModeSwitchValueChanged(app, event)
            if strcmpi(app.AutomaticModeSwitch.Value, 'On')
                app.logMessage('Auto Mode ON: Syncing targets to current pose...');
                app.syncTargetsToCurrentPose();
            else
                app.StatusLabel.Text = 'Auto Mode Off';
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PathPlannerMenu_2 = uimenu(app.UIFigure);
            app.PathPlannerMenu_2.Text = 'Path Planner';
            app.AboutPathPlannerMenu = uimenu(app.PathPlannerMenu_2);
            app.AboutPathPlannerMenu.Text = 'About PathPlanner';
            app.SettingMenu_2 = uimenu(app.PathPlannerMenu_2);
            app.SettingMenu_2.Text = 'Setting';
            app.KeyboardMappingMenu = uimenu(app.SettingMenu_2);
            app.KeyboardMappingMenu.Text = 'Keyboard Mapping';
            app.WindowMenu = uimenu(app.UIFigure);
            app.WindowMenu.Text = 'Window';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.GridLayout10 = uigridlayout(app.UIFigure);
            app.GridLayout12 = uigridlayout(app.GridLayout10);
            app.MotionControlPanel = uipanel(app.GridLayout12);
            app.MotionControlPanel.Title = 'Motion Control';
            app.GridLayout8 = uigridlayout(app.MotionControlPanel);
            app.GridLayout14 = uigridlayout(app.GridLayout8);
            app.PoseControlRelativetoWorldFramePanel = uipanel(app.GridLayout14);
            app.PoseControlRelativetoWorldFramePanel.Title = 'Pose Control Relative to World Frame';
            app.XTargetEditField = uieditfield(app.PoseControlRelativetoWorldFramePanel, 'numeric');
            app.XTargetEditField.Limits = [-20 20];
            app.XTargetEditField.ValueDisplayFormat = '%.3f';
            app.YTargetEditField = uieditfield(app.PoseControlRelativetoWorldFramePanel, 'numeric');
            app.YTargetEditField.Limits = [-20 20];
            app.YTargetEditField.ValueDisplayFormat = '%.3f';
            app.ZTargetEditField = uieditfield(app.PoseControlRelativetoWorldFramePanel, 'numeric');
            app.ZTargetEditField.Limits = [-30 30];
            app.ZTargetEditField.ValueDisplayFormat = '%.3f';
            app.PhiTargetEditField = uieditfield(app.PoseControlRelativetoWorldFramePanel, 'numeric');
            app.PhiTargetEditField.Limits = [-45 45];
            app.PhiTargetEditField.ValueDisplayFormat = '%.2f';
            app.XMinusButton = uibutton(app.PoseControlRelativetoWorldFramePanel, 'push');
            app.XMinusButton.ButtonPushedFcn = createCallbackFcn(app, @XMinusButtonPushed, true);
            app.XMinusButton.Text = '-';
            app.XPlusButton = uibutton(app.PoseControlRelativetoWorldFramePanel, 'push');
            app.XPlusButton.ButtonPushedFcn = createCallbackFcn(app, @XPlusButtonPushed, true);
            app.XPlusButton.Text = '+';
            app.mmLabel = uilabel(app.PoseControlRelativetoWorldFramePanel);
            app.mmLabel.Text = 'mm';
            app.X_setLabel = uilabel(app.PoseControlRelativetoWorldFramePanel);
            app.X_setLabel.Interpreter = 'latex';
            app.X_setLabel.Text = '$X_{set}$';
            app.mmLabel_2 = uilabel(app.PoseControlRelativetoWorldFramePanel);
            app.mmLabel_2.Text = 'mm';
            app.Y_setLabel = uilabel(app.PoseControlRelativetoWorldFramePanel);
            app.Y_setLabel.Interpreter = 'latex';
            app.Y_setLabel.Text = '$Y_{set}$';
            app.YMinusButton = uibutton(app.PoseControlRelativetoWorldFramePanel, 'push');
            app.YMinusButton.ButtonPushedFcn = createCallbackFcn(app, @YMinusButtonPushed, true);
            app.YMinusButton.Text = '-';
            app.YPlusButton = uibutton(app.PoseControlRelativetoWorldFramePanel, 'push');
            app.YPlusButton.ButtonPushedFcn = createCallbackFcn(app, @YPlusButtonPushed, true);
            app.YPlusButton.Text = '+';
            app.Z_setLabel = uilabel(app.PoseControlRelativetoWorldFramePanel);
            app.Z_setLabel.Interpreter = 'latex';
            app.Z_setLabel.Text = '$Z_{set}$';
            app.ZMinusButton = uibutton(app.PoseControlRelativetoWorldFramePanel, 'push');
            app.ZMinusButton.ButtonPushedFcn = createCallbackFcn(app, @ZMinusButtonPushed, true);
            app.ZMinusButton.Text = '-';
            app.ZPlusButton = uibutton(app.PoseControlRelativetoWorldFramePanel, 'push');
            app.ZPlusButton.ButtonPushedFcn = createCallbackFcn(app, @ZPlusButtonPushed, true);
            app.ZPlusButton.Text = '+';
            app.mmLabel_3 = uilabel(app.PoseControlRelativetoWorldFramePanel);
            app.mmLabel_3.Text = 'mm';
            app.PhiLabel = uilabel(app.PoseControlRelativetoWorldFramePanel);
            app.PhiLabel.Interpreter = 'latex';
            app.PhiLabel.Text = '$\Phi_{set}$';
            app.Label = uilabel(app.PoseControlRelativetoWorldFramePanel);
            app.Label.Text = '°';
            app.PhiMinusButton = uibutton(app.PoseControlRelativetoWorldFramePanel, 'push');
            app.PhiMinusButton.ButtonPushedFcn = createCallbackFcn(app, @PhiMinusButtonPushed, true);
            app.PhiMinusButton.Text = '-';
            app.PhiPlusButton = uibutton(app.PoseControlRelativetoWorldFramePanel, 'push');
            app.PhiPlusButton.ButtonPushedFcn = createCallbackFcn(app, @PhiPlusButtonPushed, true);
            app.PhiPlusButton.Text = '+';
            app.ThetaLabel = uilabel(app.PoseControlRelativetoWorldFramePanel);
            app.ThetaLabel.Interpreter = 'latex';
            app.ThetaLabel.Text = '$\Theta_{set}$';
            app.ThetaTargetEditField = uieditfield(app.PoseControlRelativetoWorldFramePanel, 'numeric');
            app.ThetaTargetEditField.Limits = [-90 0];
            app.ThetaTargetEditField.ValueDisplayFormat = '%.2f';
            app.ThetaTargetEditField.Value = -45;
            app.ThetaMinusButton = uibutton(app.PoseControlRelativetoWorldFramePanel, 'push');
            app.ThetaMinusButton.ButtonPushedFcn = createCallbackFcn(app, @ThetaMinusButtonPushed, true);
            app.ThetaMinusButton.Text = '-';
            app.ThetaPlusButton = uibutton(app.PoseControlRelativetoWorldFramePanel, 'push');
            app.ThetaPlusButton.ButtonPushedFcn = createCallbackFcn(app, @ThetaPlusButtonPushed, true);
            app.ThetaPlusButton.Text = '+';
            app.Label_5 = uilabel(app.PoseControlRelativetoWorldFramePanel);
            app.Label_5.Text = '°';
            app.Theta0Label_3 = uilabel(app.PoseControlRelativetoWorldFramePanel);
            app.Theta0Label_3.Interpreter = 'latex';
            app.Theta0Label_3.Text = '$\Psi_{set}$';
            app.PsiTargetEditField = uieditfield(app.PoseControlRelativetoWorldFramePanel, 'numeric');
            app.PsiTargetEditField.Limits = [-90 0];
            app.PsiTargetEditField.ValueDisplayFormat = '%.2f';
            app.PsiMinusButton = uibutton(app.PoseControlRelativetoWorldFramePanel, 'push');
            app.PsiMinusButton.ButtonPushedFcn = createCallbackFcn(app, @PsiMinusButtonPushed, true);
            app.PsiMinusButton.Text = '-';
            app.PsiPlusButton = uibutton(app.PoseControlRelativetoWorldFramePanel, 'push');
            app.PsiPlusButton.ButtonPushedFcn = createCallbackFcn(app, @PsiPlusButtonPushed, true);
            app.PsiPlusButton.Text = '+';
            app.Label_8 = uilabel(app.PoseControlRelativetoWorldFramePanel);
            app.Label_8.Text = '°';
            app.DIstanceIncrementLabel = uilabel(app.PoseControlRelativetoWorldFramePanel);
            app.DIstanceIncrementLabel.Text = 'Δd';
            app.dStepSlider = uislider(app.PoseControlRelativetoWorldFramePanel);
            app.dStepSlider.Limits = [0.1 1];
            app.dStepSlider.MajorTicks = [0.1 0.5 1];
            app.dStepSlider.MajorTickLabels = {'0.1', '0.5', '1'};
            app.dStepSlider.MinorTicks = [0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9];
            app.dStepSlider.Step = 0.1;
            app.dStepSlider.Value = 0.1;
            app.Label_9 = uilabel(app.PoseControlRelativetoWorldFramePanel);
            app.Label_9.Text = 'Δθ';
            app.dAngleSlider = uislider(app.PoseControlRelativetoWorldFramePanel);
            app.dAngleSlider.Limits = [1 10];
            app.dAngleSlider.MajorTicks = [1 5 10];
            app.dAngleSlider.MajorTickLabels = {'1', '5', '10'};
            app.dAngleSlider.MinorTicks = [2 3 4 5 6 7 8 9];
            app.dAngleSlider.Step = 1;
            app.dAngleSlider.Value = 1;
            app.PathPlanningPanel = uipanel(app.GridLayout14);
            app.PathPlanningPanel.Title = 'Path Planning';
            app.PlanButton = uibutton(app.PathPlanningPanel, 'push');
            app.PlanButton.ButtonPushedFcn = createCallbackFcn(app, @PlanButtonPushed, true);
            app.PlanButton.Text = 'Plan';
            app.SendButton = uibutton(app.PathPlanningPanel, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            app.StartButton = uibutton(app.PathPlanningPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.GetPoseButton = uibutton(app.PathPlanningPanel, 'push');
            app.GetPoseButton.ButtonPushedFcn = createCallbackFcn(app, @GetPoseButtonPushed, true);
            app.GetPoseButton.Text = 'Get Pose';
            app.IntervalSliderLabel = uilabel(app.PathPlanningPanel);
            app.IntervalSliderLabel.Text = 'Interval';
            app.IntervalSlider = uislider(app.PathPlanningPanel);
            app.IntervalSlider.Limits = [0.1 1];
            app.IntervalSlider.MajorTicks = [0.1 0.5 1];
            app.IntervalSlider.MajorTickLabels = {'0.1', '0.5', '1'};
            app.IntervalSlider.MinorTicks = [0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9];
            app.IntervalSlider.Step = 0.1;
            app.IntervalSlider.Value = 0.2;
            app.FidelitySliderLabel = uilabel(app.PathPlanningPanel);
            app.FidelitySliderLabel.Text = 'Fidelity';
            app.FidelitySlider = uislider(app.PathPlanningPanel);
            app.FidelitySlider.Limits = [10 100];
            app.FidelitySlider.MajorTicks = [10 20 30 40 50 60 70 80 90 100];
            app.FidelitySlider.MajorTickLabels = {''};
            app.FidelitySlider.MinorTicks = [10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.FidelitySlider.Step = 10;
            app.FidelitySlider.Value = 10;
            app.StatusLabel = uilabel(app.PathPlanningPanel);
            app.StatusLabel.Text = 'idle';
            app.GridLayout16 = uigridlayout(app.GridLayout8);
            app.ToolAxialFeedPanel = uipanel(app.GridLayout16);
            app.ToolAxialFeedPanel.Title = 'Tool Axial Feed';
            app.Theta0Label_4 = uilabel(app.ToolAxialFeedPanel);
            app.Theta0Label_4.Interpreter = 'latex';
            app.Theta0Label_4.Text = '$S_{tool}$';
            app.AxialFeedTargetEditField = uieditfield(app.ToolAxialFeedPanel, 'numeric');
            app.AxialFeedTargetEditField.Limits = [-30 30];
            app.AxialFeedTargetEditField.ValueDisplayFormat = '%.3f';
            app.ZMinusButton_2 = uibutton(app.ToolAxialFeedPanel, 'push');
            app.ZMinusButton_2.Text = '-';
            app.ZPlusButton_2 = uibutton(app.ToolAxialFeedPanel, 'push');
            app.ZPlusButton_2.Text = '+';
            app.mmLabel_8 = uilabel(app.ToolAxialFeedPanel);
            app.mmLabel_8.Text = 'mm';
            app.dSlider_2Label = uilabel(app.ToolAxialFeedPanel);
            app.dSlider_2Label.Text = 'Δd';
            app.dSlider_2 = uislider(app.ToolAxialFeedPanel);
            app.dSlider_2.Limits = [0.01 1];
            app.dSlider_2.MajorTicks = [0.01 0.5 1];
            app.dSlider_2.MajorTickLabels = {'0.01', '0.5', '1'};
            app.dSlider_2.MinorTicks = [0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9];
            app.dSlider_2.Step = 0.01;
            app.dSlider_2.Value = 0.01;
            app.SteppingPanel = uipanel(app.GridLayout16);
            app.SteppingPanel.Title = 'Stepping';
            app.SpeedSliderLabel = uilabel(app.SteppingPanel);
            app.SpeedSliderLabel.Text = 'Speed';
            app.SpeedSlider = uislider(app.SteppingPanel);
            app.StepButton = uibutton(app.SteppingPanel, 'push');
            app.StepButton.Text = 'Step';
            app.MRExUnitPanel = uipanel(app.GridLayout12);
            app.MRExUnitPanel.Title = 'M-REx Unit';
            app.GridLayout15 = uigridlayout(app.MRExUnitPanel);
            app.ConfigurationPanel = uipanel(app.GridLayout15);
            app.ConfigurationPanel.Title = 'Configuration';
            app.Image = uiimage(app.ConfigurationPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'assets', 'figure_2R_RCM.png');
            app.ManipulatorLeft = uidropdown(app.ConfigurationPanel);
            app.ManipulatorLeft.Items = {'MC1', 'MC2', 'MC3', 'MC4'};
            app.ManipulatorLeft.Value = 'MC1';
            app.ManipulatorRight = uidropdown(app.ConfigurationPanel);
            app.ManipulatorRight.Items = {'MC1', 'MC2', 'MC3', 'MC4'};
            app.ManipulatorRight.Value = 'MC2';
            app.mmLabel_7 = uilabel(app.ConfigurationPanel);
            app.mmLabel_7.Text = 'mm';
            app.Label_3 = uilabel(app.ConfigurationPanel);
            app.Label_3.Text = '°';
            app.ModelDropDownLabel = uilabel(app.ConfigurationPanel);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.ConfigurationPanel);
            app.ModelDropDown.Items = {'model_3T1R_Roll', 'model_3T2R_Pitch_Roll', 'model_3T2R_Roll_Yaw', 'model_3T3R_Yaw_Pitch_Roll'};
            app.ModelDropDown.Value = 'model_3T1R_Roll';
            app.TipDistanceEditFieldLabel = uilabel(app.ConfigurationPanel);
            app.TipDistanceEditFieldLabel.Text = 'Tip Distance';
            app.TipDistanceEditField = uieditfield(app.ConfigurationPanel, 'numeric');
            app.TipDistanceEditField.Value = 15;
            app.InitialTiltAngleEditFieldLabel = uilabel(app.ConfigurationPanel);
            app.InitialTiltAngleEditFieldLabel.Text = 'Initial Tilt Angle';
            app.InitialTiltAngleEditField = uieditfield(app.ConfigurationPanel, 'numeric');
            app.InitialTiltAngleEditField.Value = 45;
            app.LeftManipulatorDropDownLabel = uilabel(app.ConfigurationPanel);
            app.LeftManipulatorDropDownLabel.Text = 'Left Manipulator';
            app.LeftManipulatorDropDown = uidropdown(app.ConfigurationPanel);
            app.LeftManipulatorDropDown.Items = {'MC1', 'MC2', 'MC3', 'MC4'};
            app.LeftManipulatorDropDown.Value = 'MC1';
            app.RightManipulatorDropDownLabel = uilabel(app.ConfigurationPanel);
            app.RightManipulatorDropDownLabel.Text = 'Right Manipulator';
            app.RightManipulatorDropDown = uidropdown(app.ConfigurationPanel);
            app.RightManipulatorDropDown.Items = {'MC1', 'MC2', 'MC3', 'MC4'};
            app.RightManipulatorDropDown.Value = 'MC2';
            app.PoseinWorldFramePanel = uipanel(app.GridLayout15);
            app.PoseinWorldFramePanel.Title = 'Pose in World Frame';
            app.YactLabel = uilabel(app.PoseinWorldFramePanel);
            app.YactLabel.Interpreter = 'latex';
            app.YactLabel.Text = '$Y_{act}$';
            app.Z0Label = uilabel(app.PoseinWorldFramePanel);
            app.Z0Label.Interpreter = 'latex';
            app.Z0Label.Text = '$Z_{act}$';
            app.mmLabel_4 = uilabel(app.PoseinWorldFramePanel);
            app.mmLabel_4.Text = 'mm';
            app.mmLabel_5 = uilabel(app.PoseinWorldFramePanel);
            app.mmLabel_5.Text = 'mm';
            app.mmLabel_6 = uilabel(app.PoseinWorldFramePanel);
            app.mmLabel_6.Text = 'mm';
            app.LabelY0 = uilabel(app.PoseinWorldFramePanel);
            app.LabelY0.Text = '0.000';
            app.LabelZ0 = uilabel(app.PoseinWorldFramePanel);
            app.LabelZ0.Text = '0.000';
            app.LabelX0 = uilabel(app.PoseinWorldFramePanel);
            app.LabelX0.Text = '0.000';
            app.XactLabel = uilabel(app.PoseinWorldFramePanel);
            app.XactLabel.Interpreter = 'latex';
            app.XactLabel.Text = '$X_{act}$';
            app.Phi0Label = uilabel(app.PoseinWorldFramePanel);
            app.Phi0Label.Interpreter = 'latex';
            app.Phi0Label.Text = '$\Phi_{act}$';
            app.LabelCelsius = uilabel(app.PoseinWorldFramePanel);
            app.LabelCelsius.Text = '°';
            app.LabelPhi0 = uilabel(app.PoseinWorldFramePanel);
            app.LabelPhi0.Text = '0.00';
            app.Theta0Label = uilabel(app.PoseinWorldFramePanel);
            app.Theta0Label.Interpreter = 'latex';
            app.Theta0Label.Text = '$\Theta_{act}$';
            app.LabelTheta0 = uilabel(app.PoseinWorldFramePanel);
            app.LabelTheta0.Text = '0.00';
            app.LabelCelsius_2 = uilabel(app.PoseinWorldFramePanel);
            app.LabelCelsius_2.Text = '°';
            app.Theta0Label_2 = uilabel(app.PoseinWorldFramePanel);
            app.Theta0Label_2.Interpreter = 'latex';
            app.Theta0Label_2.Text = '$\Psi_{act}$';
            app.LabelCelsius_3 = uilabel(app.PoseinWorldFramePanel);
            app.LabelCelsius_3.Text = '°';
            app.LabelPsi0 = uilabel(app.PoseinWorldFramePanel);
            app.LabelPsi0.Text = '0.00';
            app.GridLayout13 = uigridlayout(app.GridLayout10);
            app.GraphPanel = uipanel(app.GridLayout13);
            app.GraphPanel.Title = 'Graph Panel';
            app.GridLayout11 = uigridlayout(app.GraphPanel);
            app.TabGroup = uitabgroup(app.GridLayout11);
            app.VisualizationTab = uitab(app.TabGroup);
            app.VisualizationTab.Title = 'Visualization';
            app.GridLayout6 = uigridlayout(app.VisualizationTab);
            app.VisualizePanel = uiaxes(app.GridLayout6);
            title(app.VisualizePanel, 'Title')
            xlabel(app.VisualizePanel, 'X')
            ylabel(app.VisualizePanel, 'Y')
            zlabel(app.VisualizePanel, 'Z')
            app.TrajectoryTab = uitab(app.TabGroup);
            app.TrajectoryTab.Title = 'Trajectory';
            app.GridLayout7 = uigridlayout(app.TrajectoryTab);
            app.UIAxes2 = uiaxes(app.GridLayout7);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes1 = uiaxes(app.GridLayout7);
            title(app.UIAxes1, 'Title')
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            zlabel(app.UIAxes1, 'Z')
            app.CommStatusPanel = uipanel(app.GridLayout13);
            app.CommStatusPanel.Title = 'Comm Status';
            app.ConnectButton = uibutton(app.CommStatusPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.CommStatusPanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.StatusLampLabel = uilabel(app.CommStatusPanel);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.CommStatusPanel);
            app.CommunicationLogLabel = uilabel(app.CommStatusPanel);
            app.CommunicationLogLabel.Text = 'TCP Comm Log';
            app.TCPCommLogTextArea = uitextarea(app.CommStatusPanel);
            app.ClearLogButton = uibutton(app.CommStatusPanel, 'push');
            app.ClearLogButton.ButtonPushedFcn = createCallbackFcn(app, @ClearLogButtonPushed, true);
            app.ClearLogButton.Text = 'Clear Log';
            app.ExportPathButton = uibutton(app.CommStatusPanel, 'push');
            app.ExportPathButton.ButtonPushedFcn = createCallbackFcn(app, @ExportPathButtonPushed, true);
            app.ExportPathButton.Text = 'Export Path';
            app.Label_6 = uilabel(app.CommStatusPanel);
            app.Label_6.Text = 'File Location';
            app.ExportLocationEditField = uieditfield(app.CommStatusPanel, 'text');
            app.ExportLocationEditField.Value = 'data/';
            app.AutomaticModeSwitchLabel = uilabel(app.CommStatusPanel);
            app.AutomaticModeSwitchLabel.Text = 'Automatic Mode';
            app.AutomaticModeSwitch = uiswitch(app.CommStatusPanel, 'slider');
            app.AutomaticModeSwitch.ValueChangedFcn = createCallbackFcn(app, @AutomaticModeSwitchValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PathPlanner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end